classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        LodingButton             matlab.ui.control.Button
        LodingButton_2           matlab.ui.control.Button
        ThresholdEditFieldLabel  matlab.ui.control.Label
        ThresholdEditField       matlab.ui.control.NumericEditField
        DirectionDropDownLabel   matlab.ui.control.Label
        DirectionDropDown        matlab.ui.control.DropDown
        STARTButton              matlab.ui.control.Button
        ResultTextAreaLabel      matlab.ui.control.Label
        ResultTextArea           matlab.ui.control.TextArea
        LinewidthDropDownLabel   matlab.ui.control.Label
        LinewidthDropDown        matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        Im1; % Description
        Im2;
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes,'off');
            axis(app.UIAxes_2,'off');
        end

        % Button pushed function: LodingButton
        function LodingButtonPushed(app, event)
          [filename,pathname]=uigetfile({'*.jpg';'*.png';'*.tif';'*.jpeg'});
          if filename==0
              warndlg('请选择图片','警告');                  
              return
          end
          image=imread([pathname,filename]);
          app.Im1=image;
          imshow(image,'parent',app.UIAxes);
        end

        % Button pushed function: LodingButton_2
        function LodingButton_2Pushed(app, event)
          [filename,pathname]=uigetfile({'*.jpg';'*.png';'*.tif';'*.jpeg'});
          if filename==0
              warndlg('请选择图片','警告');                  
              return
          end
          image=imread([pathname,filename]);
          app.Im2=image;
          imshow(image,'parent',app.UIAxes_2);
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.ResultTextArea.Value="Detecting";
            threshold=app.ThresholdEditField.Value;
            direction=app.DirectionDropDown.Value;
            linewidth=str2double(app.LinewidthDropDown.Value);
            hash1=Hash_Generation(app.Im1,direction);
            hash2=Hash_Generation(app.Im2,direction);
            Tamper_result=Tampering_Detection(hash1,hash2,8);
            [CordinatesLu, CordinatesRd,~]=Region_Decide(Tamper_result,threshold);
            if isempty(CordinatesLu)
                app.ResultTextArea.Value="Undetected";
                return
            end
            final_im1=Originalmage_Region_Decide(app.Im1,CordinatesLu,CordinatesRd,linewidth);
            final_im2=Originalmage_Region_Decide(app.Im2,CordinatesLu,CordinatesRd,linewidth);
            imshow(final_im1,'parent',app.UIAxes);
            imshow(final_im2,'parent',app.UIAxes_2);
            app.ResultTextArea.Value="Detected";
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image1')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Image2')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.LodingButton = uibutton(app.UIFigure, 'push');
            app.LodingButton.ButtonPushedFcn = createCallbackFcn(app, @LodingButtonPushed, true);
            app.LodingButton.Text = 'Loding';
            app.LodingButton_2 = uibutton(app.UIFigure, 'push');
            app.LodingButton_2.ButtonPushedFcn = createCallbackFcn(app, @LodingButton_2Pushed, true);
            app.LodingButton_2.Text = 'Loding';
            app.ThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdEditField.Value = 0.4;
            app.DirectionDropDownLabel = uilabel(app.UIFigure);
            app.DirectionDropDownLabel.Text = 'Direction';
            app.DirectionDropDown = uidropdown(app.UIFigure);
            app.DirectionDropDown.Items = {'Transverse', 'Lengthway', 'Diagonal'};
            app.DirectionDropDown.Value = 'Transverse';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.ResultTextAreaLabel = uilabel(app.UIFigure);
            app.ResultTextAreaLabel.Text = 'Result';
            app.ResultTextArea = uitextarea(app.UIFigure);
            app.ResultTextArea.Editable = 'off';
            app.ResultTextArea.Tooltip = {'dd'; ''};
            app.LinewidthDropDownLabel = uilabel(app.UIFigure);
            app.LinewidthDropDownLabel.Text = 'Linewidth';
            app.LinewidthDropDown = uidropdown(app.UIFigure);
            app.LinewidthDropDown.Items = {'3', '5', '7', '9'};
            app.LinewidthDropDown.Value = '3';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end